classdef lab4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MATLABSimulinkL4Label  matlab.ui.control.Label
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        outputLabel            matlab.ui.control.Label
        inputLabel             matlab.ui.control.Label
        CalculateButton        matlab.ui.control.Button
        bEditField             matlab.ui.control.NumericEditField
        bEditFieldLabel        matlab.ui.control.Label
        aEditField             matlab.ui.control.NumericEditField
        aEditFieldLabel        matlab.ui.control.Label
        abEditField_4          matlab.ui.control.NumericEditField
        abEditField_4Label     matlab.ui.control.Label
        abEditField_3          matlab.ui.control.NumericEditField
        abEditField_3Label     matlab.ui.control.Label
        abEditField_2          matlab.ui.control.NumericEditField
        abEditField_2Label     matlab.ui.control.Label
        abEditField            matlab.ui.control.NumericEditField
        abEditFieldLabel       matlab.ui.control.Label
        Tab2                   matlab.ui.container.Tab
        freqSlider             matlab.ui.control.Slider
        freqSliderLabel        matlab.ui.control.Label
        AmpSpinner             matlab.ui.control.Spinner
        AmpSpinnerLabel        matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.abEditField.Value = app.aEditField.Value + app.bEditField.Value
            app.abEditField_2.Value = app.aEditField.Value - app.bEditField.Value
            app.abEditField_3.Value = app.aEditField.Value * app.bEditField.Value
            app.abEditField_4.Value = app.aEditField.Value / app.bEditField.Value
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.abEditFieldLabel = uilabel(app.Tab);
            app.abEditFieldLabel.Text = 'a+b';
            app.abEditField = uieditfield(app.Tab, 'numeric');
            app.abEditField_2Label = uilabel(app.Tab);
            app.abEditField_2Label.Text = 'a-b';
            app.abEditField_2 = uieditfield(app.Tab, 'numeric');
            app.abEditField_3Label = uilabel(app.Tab);
            app.abEditField_3Label.Text = 'a*b';
            app.abEditField_3 = uieditfield(app.Tab, 'numeric');
            app.abEditField_4Label = uilabel(app.Tab);
            app.abEditField_4Label.Text = 'a/b';
            app.abEditField_4 = uieditfield(app.Tab, 'numeric');
            app.aEditFieldLabel = uilabel(app.Tab);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Tab, 'numeric');
            app.aEditField.Value = 20;
            app.bEditFieldLabel = uilabel(app.Tab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Tab, 'numeric');
            app.bEditField.Value = 40;
            app.CalculateButton = uibutton(app.Tab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.inputLabel = uilabel(app.Tab);
            app.inputLabel.Text = 'input:';
            app.outputLabel = uilabel(app.Tab);
            app.outputLabel.Text = 'output:';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes = uiaxes(app.Tab2);
            title(app.UIAxes, 'XY')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AmpSpinnerLabel = uilabel(app.Tab2);
            app.AmpSpinnerLabel.Text = 'Amp';
            app.AmpSpinner = uispinner(app.Tab2);
            app.freqSliderLabel = uilabel(app.Tab2);
            app.freqSliderLabel.Text = 'freq';
            app.freqSlider = uislider(app.Tab2);
            app.freqSlider.Limits = [0 6];
            app.MATLABSimulinkL4Label = uilabel(app.UIFigure);
            app.MATLABSimulinkL4Label.Text = '電資通基礎MATLAB / Simulink實驗 L4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end